classdef Copy_of_regrationRMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ActualRMSEditField          matlab.ui.control.NumericEditField
        ActualRMSEditFieldLabel     matlab.ui.control.Label
        DiametermmEditField         matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel    matlab.ui.control.Label
        RPMEditField                matlab.ui.control.NumericEditField
        RPMEditFieldLabel           matlab.ui.control.Label
        DepthofcutmmEditField       matlab.ui.control.NumericEditField
        DepthofcutmmEditFieldLabel  matlab.ui.control.Label
        FeedmmrecEditField          matlab.ui.control.NumericEditField
        FeedmmrecEditFieldLabel     matlab.ui.control.Label
        CuttingSpeedmminEditField   matlab.ui.control.NumericEditField
        CuttingSpeedmminEditField_2Label  matlab.ui.control.Label
        PredictButton               matlab.ui.control.Button
        ModeselectionSwitch         matlab.ui.control.Switch
        ModeselectionSwitchLabel    matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        PredictedRMSEditField       matlab.ui.control.NumericEditField
        PredictedRMSEditFieldLabel  matlab.ui.control.Label
        RMSGauge                    matlab.ui.control.SemicircularGauge
        RMSGaugeLabel               matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LoadData(app)
            load("meanRMS.mat");
            %defining var
            CuttingSpeed_m_min_= app.CuttingSpeedmminEditField.Value;
            Feed_mm_rec_= app.FeedmmrecEditField.Value;
            DepthOfCut_mm_= app.DepthofcutmmEditField.Value;
            RPM= app.RPMEditField.Value;
            Diameter_mm_= app.DiametermmEditField.Value;
            app.ActualRMSEditField.Value=30;
            RMSMean= app.ActualRMSEditField.Value;
            % Description
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            load("meanRMS.mat")
            %defining var
            CuttingSpeed_m_min_= app.CuttingSpeedmminEditField.Value;
            Feed_mm_rec_= app.FeedmmrecEditField.Value;
            DepthOfCut_mm_= app.DepthofcutmmEditField.Value;
            RPM= app.RPMEditField.Value;
            Diameter_mm_= app.DiametermmEditField.Value;
            RMSMean= app.ActualRMSEditField.Value;
            %making prediction
            pred= table(CuttingSpeed_m_min_, Feed_mm_rec_, DepthOfCut_mm_, RPM, Diameter_mm_, RMSMean);
            yfit = meanRMS.predictFcn(pred);
            %assigning value to displays
            app.PredictedRMSEditField.Value= yfit;
            app.RMSGauge.Value = yfit;
            %plot
            plot(app.UIAxes, yfit,'.r');
            hold(app.UIAxes)
            plot(app.UIAxes,RMSMean,'.b');
            %            UiAxes.legend('yfit','RMSmean');
        end

        % Value changed function: ModeselectionSwitch
        function ModeselectionSwitchValueChanged(app, event)
            load("meanRMS.mat");
            %defining var

            % Description
            %making prediction
            value= app.ModeselectionSwitch.Value
            while true
                if strcmp(value,'Pre-test');
                    app.PredictButton.Enable = 'on' ;
                    display(value)
                    break
                else
                    app.PredictButton.Enable = 'off' ;
                    CuttingSpeed_m_min_= app.CuttingSpeedmminEditField.Value;
                    Feed_mm_rec_= app.FeedmmrecEditField.Value;
                    DepthOfCut_mm_= app.DepthofcutmmEditField.Value;
                    RPM= app.RPMEditField.Value;
                    Diameter_mm_= app.DiametermmEditField.Value;
                    app.ActualRMSEditField.Value=30;
                    RMSMean= app.ActualRMSEditField.Value;
                    pred= table(CuttingSpeed_m_min_, Feed_mm_rec_, DepthOfCut_mm_, RPM, Diameter_mm_, RMSMean);
                    yfit = meanRMS.predictFcn(pred);
                    %assigning value to displays
                    app.PredictedRMSEditField.Value= yfit;
                    app.RMSGauge.Value = yfit;
                    %plot
                    plot(app.UIAxes, yfit,'.r');
                    hold(app.UIAxes)
                    plot(app.UIAxes,RMSMean,'.b');
                    pause(2)
                end
            end


% 
%             if strcmp(value,'Live'); %some problem here
%                 app.PredictButton.Enable = 'off' ;
%                 while
%                     CuttingSpeed_m_min_= app.CuttingSpeedmminEditField.Value;
%                     Feed_mm_rec_= app.FeedmmrecEditField.Value;
%                     DepthOfCut_mm_= app.DepthofcutmmEditField.Value;
%                     RPM= app.RPMEditField.Value;
%                     Diameter_mm_= app.DiametermmEditField.Value;
%                     app.ActualRMSEditField.Value=30;
%                     RMSMean= app.ActualRMSEditField.Value;
%                     pred= table(CuttingSpeed_m_min_, Feed_mm_rec_, DepthOfCut_mm_, RPM, Diameter_mm_, RMSMean);
%                     yfit = meanRMS.predictFcn(pred);
%                     %assigning value to displays
%                     app.PredictedRMSEditField.Value= yfit;
%                     app.RMSGauge.Value = yfit;
%                     %plot
%                     plot(app.UIAxes, yfit,'.r');
%                     hold(app.UIAxes)
%                     plot(app.UIAxes,RMSMean,'.b');
%                     pause(2)
%                 end
%             else
% 
%                 app.PredictButton.Enable = 'on' ;
%                 display(value)
%                 ;
%             end
% 

            %            UiAxes.legend('yfit','RMSmean');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModeselectionSwitchLabel = uilabel(app.LeftPanel);
            app.ModeselectionSwitchLabel.Text = 'Mode selection';
            app.ModeselectionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ModeselectionSwitch.Items = {'Pre-test', 'Live'};
            app.ModeselectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeselectionSwitchValueChanged, true);
            app.ModeselectionSwitch.Value = 'Pre-test';
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.CuttingSpeedmminEditField_2Label = uilabel(app.LeftPanel);
            app.CuttingSpeedmminEditField_2Label.Text = 'Cutting Speed (m/min)';
            app.CuttingSpeedmminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FeedmmrecEditFieldLabel = uilabel(app.LeftPanel);
            app.FeedmmrecEditFieldLabel.Text = 'Feed (mm/rec)';
            app.FeedmmrecEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DepthofcutmmEditFieldLabel = uilabel(app.LeftPanel);
            app.DepthofcutmmEditFieldLabel.Text = 'Depth of cut (mm)';
            app.DepthofcutmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RPMEditFieldLabel = uilabel(app.LeftPanel);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiametermmEditFieldLabel = uilabel(app.LeftPanel);
            app.DiametermmEditFieldLabel.Text = 'Diameter(mm)';
            app.DiametermmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ActualRMSEditFieldLabel = uilabel(app.LeftPanel);
            app.ActualRMSEditFieldLabel.Text = 'Actual RMS';
            app.ActualRMSEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'RMS predictions')
            xlabel(app.UIAxes, 'Actual RMS')
            ylabel(app.UIAxes, 'Predicted RMS')
            zlabel(app.UIAxes, 'Z')
            app.RMSGaugeLabel = uilabel(app.RightPanel);
            app.RMSGaugeLabel.Text = 'RMS';
            app.RMSGauge = uigauge(app.RightPanel, 'semicircular');
            app.RMSGauge.Limits = [-600 0];
            app.PredictedRMSEditFieldLabel = uilabel(app.RightPanel);
            app.PredictedRMSEditFieldLabel.Text = 'Predicted RMS';
            app.PredictedRMSEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_regrationRMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @LoadData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end